classdef CK_Ten < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        DrawButton                     matlab.ui.control.Button
        SlnlpEditFieldLabel            matlab.ui.control.Label
        SlnlpEditField                 matlab.ui.control.NumericEditField
        ChnphngphptmChiailpNewtontiptuynEditFieldLabel  matlab.ui.control.Label
        ChnphngphptmChiailpNewtontiptuynEditField  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimEditField    matlab.ui.control.EditField
        NhpsaischophpEditField_3Label  matlab.ui.control.Label
        NhpsaischophpEditField         matlab.ui.control.EditField
        KtqunghimEditField_3Label      matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        DrawButton_2                   matlab.ui.control.Button
        ChnphngphpnisuyNisuyNewtonLagrangeLabel  matlab.ui.control.Label
        ChnphngphpnisuyNisuyNewtonLagrangeEditField  matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        NhpdliuxEditField              matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        NhpdliuyEditField              matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NhpgitrcnnisuyEditField        matlab.ui.control.EditField
        KtqunisuyEditField_3Label      matlab.ui.control.Label
        KtqunisuyEditField             matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        KtquathcnisuyEditField         matlab.ui.control.EditField
        UIAxes_2                       matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        DrawButton_3                   matlab.ui.control.Button
        ChnphngphphisuyTuyntnhhmmLogaritEditFieldLabel  matlab.ui.control.Label
        ChnphngphphisuyTuyntnhhmmLogaritEditField  matlab.ui.control.EditField
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        NhpdliuxEditField_2            matlab.ui.control.EditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        NhpdliuyEditField_2            matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        NhpgitrcndonEditField          matlab.ui.control.EditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        KtqudonEditField               matlab.ui.control.EditField
        KtquphngtrnhhisuyEditFieldLabel  matlab.ui.control.Label
        KtquphngtrnhhisuyEditField     matlab.ui.control.EditField
        UIAxes_3                       matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        TchphnTab                      matlab.ui.container.Tab
        GiithiuNhmTab                  matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            % Đọc hàm số từ trường nhập liệu
            funcStr = app.NhpphngtrnhEditField.Value;
            syms x;
            try
                f = str2func(['@(x)', funcStr]);  % Chuyển chuỗi thành hàm ẩn danh
                df = matlabFunction(diff(f(x), x));  % Tính đạo hàm
            catch
                uialert(app.UIFigure, 'Hàm số nhập không hợp lệ hoặc không thể tính đạo hàm.', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc khoảng x từ trường nhập liệu
            rangeStr = app.NhpkhongphnlynghimEditField.Value;
            rangeStr = strrep(rangeStr, ' ', '');  % Xóa khoảng trắng
            range = str2num(rangeStr);  % Chuyển chuỗi thành vector số
            if isempty(range) || length(range) ~= 2
                uialert(app.UIFigure, 'Nhập khoảng x không đúng. Nhập lại dạng "a,b".', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc sai số cho phép từ trường nhập liệu
            toleranceStr = app.NhpsaischophpEditField.Value;
            tolerance = str2double(toleranceStr);
            if isnan(tolerance) || tolerance <= 0
                uialert(app.UIFigure, 'Nhập sai số cho phép không hợp lệ. Sai số phải là một số dương.', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc phương pháp tìm nghiệm từ dropdown
            method = app.ChnphngphptmChiailpNewtontiptuynEditField.Value;
            
            % Kiểm tra nhập liệu và vẽ đồ thị
            fplot(app.UIAxes, f, range);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Đồ thị hàm số');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            
            % Tính toán nghiệm và số lần lặp tùy theo phương pháp
            try
                switch method
                    case 'Chia đôi'
                        [root, iterations] = chiadoi(f, range(1), range(2), tolerance);
                    case 'Lặp'
                        initialGuess = (range(1) + range(2)) / 2;
                        fp = @(x) x - f(x) / (df(x) + eps);  % Sử dụng đạo hàm đã tính
                        [root, iterations] = lap(fp, initialGuess, tolerance);
                    case 'Newton'
                        initialGuess = (range(1) + range(2)) / 2;
                        [root, iterations] = tieptuyen(f, initialGuess, 0, tolerance);
                    otherwise
                        uialert(app.UIFigure, 'Phương pháp không được hỗ trợ.', 'Lỗi');
                        return;
                end
            catch ME
                uialert(app.UIFigure, ['Có lỗi xảy ra: ', ME.message], 'Lỗi tính toán');
                return;
            end
            
            % Hiển thị kết quả nghiệm và số lần lặp
            app.KtqunghimEditField.Value = num2str(root);
            app.SlnlpEditField.Value = iterations;  % Đảm bảo rằng số lần lặp là một chuỗi


        end

        % Button pushed function: DrawButton_2
        function DrawButton_2Pushed(app, event)
            xa = str2num(app.NhpdliuxEditField.Value);  % Đọc dữ liệu X từ giao diện
            ya = str2num(app.NhpdliuyEditField.Value);  % Đọc dữ liệu Y từ giao diện
            x_interpolate = str2double(app.NhpgitrcnnisuyEditField.Value);  % Đọc giá trị X cần nội suy
        
            if isempty(xa) || isempty(ya) || length(xa) ~= length(ya)
                uialert(app.UIFigure, 'Vui lòng nhập đầy đủ và chính xác dữ liệu X và Y.', 'Lỗi nhập liệu');
                return;
            end
        
            phuongPhapNoiSuy = app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Value;  % Giả định có một dropdown để chọn phương pháp
        
            % Thực hiện nội suy sử dụng phương pháp đã chọn
            switch phuongPhapNoiSuy
                case 'Lagrange'
                    p = @(x) Lagrange(xa, ya, x);
                case 'Newton'
                    p = @(x) NewtonInterpolation(xa, ya, x);
                otherwise
                    uialert(app.UIFigure, 'Phương pháp nội suy không được hỗ trợ.', 'Error');
                    return;
            end
        
            % Vẽ đồ thị
            fplot(app.UIAxes_2, p, [min(xa), max(xa)]);
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, xa, ya, 'ro');
            hold(app.UIAxes_2, 'off');
            title(app.UIAxes_2, 'Đồ thị nội suy');
            xlabel(app.UIAxes_2, 'X');
            ylabel(app.UIAxes_2, 'Y');
            grid(app.UIAxes_2, 'on');
            legend(app.UIAxes_2, 'Đa thức nội suy', 'Dữ liệu ban đầu');
        
            % Tính giá trị nội suy tại điểm x_interpolate
            interpolated_value = p(x_interpolate);
            app.KtqunisuyEditField.Value = num2str(interpolated_value, '%.4f');
        
            % Hiển thị đa thức
            app.KtquathcnisuyEditField.Value = char(vpa(p, 4));  % Biểu diễn đa thức thành chuỗi
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.DrawButton = uibutton(app.NghimTab, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphptmChiailpNewtontiptuynEditFieldLabel = uilabel(app.NghimTab);
            app.ChnphngphptmChiailpNewtontiptuynEditFieldLabel.Text = {'Chọn phương pháp tìm: '; 'Chia đôi, lặp, Newton'; '(tiếp tuyến)'};
            app.ChnphngphptmChiailpNewtontiptuynEditField = uieditfield(app.NghimTab, 'text');
            app.ChnphngphptmChiailpNewtontiptuynEditField.Value = 'Lặp';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpphngtrnhEditField.Value = 'x-sin(x)-0.25';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = {'Nhập khoảng phân ly'; ' nghiệm'};
            app.NhpkhongphnlynghimEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimEditField.Value = '-1,2';
            app.NhpsaischophpEditField_3Label = uilabel(app.NghimTab);
            app.NhpsaischophpEditField_3Label.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditField.Value = '0.005';
            app.KtqunghimEditField_3Label = uilabel(app.NghimTab);
            app.KtqunghimEditField_3Label.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'text');
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.DrawButton_2 = uibutton(app.NisuyTab, 'push');
            app.DrawButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_2Pushed, true);
            app.DrawButton_2.Text = 'Draw';
            app.ChnphngphpnisuyNisuyNewtonLagrangeLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyNisuyNewtonLagrangeLabel.Text = {'Chọn phương pháp'; ' nội suy: '; 'Nội suy Newton, Lagrange'};
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Value = 'Newton';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuxEditField.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditField.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditField.Value = '0.25';
            app.KtqunisuyEditField_3Label = uilabel(app.NisuyTab);
            app.KtqunisuyEditField_3Label.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.DrawButton_3 = uibutton(app.HiquyTab, 'push');
            app.DrawButton_3.Text = 'Draw';
            app.ChnphngphphisuyTuyntnhhmmLogaritEditFieldLabel = uilabel(app.HiquyTab);
            app.ChnphngphphisuyTuyntnhhmmLogaritEditFieldLabel.Text = {'Chọn phương pháp'; ' hồi suy: '; 'Tuyến tính, hàm mũ, Logarit'};
            app.ChnphngphphisuyTuyntnhhmmLogaritEditField = uieditfield(app.HiquyTab, 'text');
            app.ChnphngphphisuyTuyntnhhmmLogaritEditField.Value = 'Newton';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuxEditField_2.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditField.Value = '0.25';
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhisuyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhisuyEditFieldLabel.Text = 'Kết quả phương trình hồi suy';
            app.KtquphngtrnhhisuyEditField = uieditfield(app.HiquyTab, 'text');
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị hàm số')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.GiithiuNhmTab = uitab(app.TabGroup);
            app.GiithiuNhmTab.Title = 'Giới thiệu Nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Ten

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end